classdef Thermal_OBJ_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunAnalysisButton               matlab.ui.control.Button
        StopAnalysisButton              matlab.ui.control.Button
        OutputFolderButton              matlab.ui.control.Button
        FolderSelection_Field           matlab.ui.control.EditField
        OBJ_File_name                   matlab.ui.control.EditField
        Texture_File_name               matlab.ui.control.EditField
        OBJFileButton                   matlab.ui.control.Button
        TextureFileButton               matlab.ui.control.Button
        PreviewOBJButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        HottestTemperatureCEditFieldLabel  matlab.ui.control.Label
        HottestTemperatureCEditField    matlab.ui.control.NumericEditField
        ColdestTemperatureCEditFieldLabel  matlab.ui.control.Label
        ColdestTemperatureCEditField    matlab.ui.control.NumericEditField
        AmbientTemperatureCEditFieldLabel  matlab.ui.control.Label
        AmbientTemperatureCEditField    matlab.ui.control.NumericEditField
        WallAngledegreesEditFieldLabel  matlab.ui.control.Label
        WallAngledegreesEditField       matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel        matlab.ui.control.Label
        EmissivityEditField             matlab.ui.control.NumericEditField
        GroundTemperatureCEditField_2Label  matlab.ui.control.Label
        GroundTemperatureCEditField     matlab.ui.control.NumericEditField
        DewPointTemperatureCEditFieldLabel  matlab.ui.control.Label
        DewPointTemperatureCEditField   matlab.ui.control.NumericEditField
        RealDistanceEditFieldLabel      matlab.ui.control.Label
        RealDistanceEditField           matlab.ui.control.NumericEditField
        OBJDistanceEditFieldLabel       matlab.ui.control.Label
        OBJDistanceEditField            matlab.ui.control.NumericEditField
        UseGlobalAngleCheckBox          matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UseGlobalAngleCheckBox
        function UseGlobalAngleCheckBoxValueChanged(app, event)
            angle_box = app.UseGlobalAngleCheckBox.Value;
            wall_ang = app.WallAngledegreesEditField;
            if angle_box
                set(wall_ang,'enable','on');
            else
                set(wall_ang,'enable','off');
                %wall_ang = 0;
            end
            
        end

        % Callback function: FolderSelection_Field, OutputFolderButton
        function FileSelectionButtonPushed(app, event)
            output_directory = uigetdir;
            app.FolderSelection_Field.Value = output_directory;
        end

        % Button pushed function: OBJFileButton
        function OBJFileButtonPushed(app, event)
            obj_file = uigetfile({'*.obj'});
            app.OBJ_File_name.Value = obj_file;
            
        end

        % Button pushed function: TextureFileButton
        function TextureFileButtonPushed(app, event)
            texture_file = uigetfile({'*.jpg';'*.png'});
            app.Texture_File_name.Value = texture_file;
        end

        % Button pushed function: StopAnalysisButton
        function StopAnalysisButtonPushed(app, event)
            return
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            toLow = double(app.ColdestTemperatureCEditField.Value);
            toHigh = double(app.HottestTemperatureCEditField.Value);
            T_amb = double(app.AmbientTemperatureCEditField.Value);
            Emissivity = double(app.EmissivityEditField.Value);
            T_dew_point = double(app.DewPointTemperatureCEditField.Value);
            T_ground = double(app.GroundTemperatureCEditField.Value);
            obj_distance = double(app.OBJDistanceEditField.Value);
            real_distance = double(app.RealDistanceEditField.Value);
            
            angle_box = app.UseGlobalAngleCheckBox.Value;
            if angle_box
                wall_ang = NaN;
            else
                wall_ang = double(app.WallAngledegreesEditField.Value); 
            end
                  
        
        parameters = [toLow, toHigh, T_amb, Emissivity, wall_ang, T_dew_point, T_ground, obj_distance, real_distance];
        
        texture_file_name =  app.Texture_File_name.Value;
        obj_file_name = app.OBJ_File_name.Value;
        waitbar(0, 'Reading OBJ File...');
        obj = readObj(obj_file_name);
        
        results = radiative_output_calculator(obj_file_name, texture_file_name, parameters, obj);
        app.UITable.Data = results(3:7,1:2);
        
        output_directory = char(app.FolderSelection_Field.Value);
        obj_file = char(app.OBJ_File_name.Value);
        obj_file = erase(obj_file, 'obj');
        
        d = datestr(datetime(now,'ConvertFrom','datenum'));
        d(d == ':') = '_'; d(d == ' ') = '_';
        
        file_name = [output_directory, '\', obj_file, d, '.xlsx'];
        writetable(results,file_name,'FileType','spreadsheet');
        
        end

        % Button pushed function: PreviewOBJButton
        function PreviewOBJButtonPushed(app, event)
    obj_display1(string(app.OBJ_File_name.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.StopAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StopAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StopAnalysisButtonPushed, true);
            app.StopAnalysisButton.Text = 'Stop Analysis';
            app.OutputFolderButton = uibutton(app.UIFigure, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectionButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.FolderSelection_Field = uieditfield(app.UIFigure, 'text');
            app.FolderSelection_Field.ValueChangingFcn = createCallbackFcn(app, @FileSelectionButtonPushed, true);
            app.OBJ_File_name = uieditfield(app.UIFigure, 'text');
            app.Texture_File_name = uieditfield(app.UIFigure, 'text');
            app.OBJFileButton = uibutton(app.UIFigure, 'push');
            app.OBJFileButton.ButtonPushedFcn = createCallbackFcn(app, @OBJFileButtonPushed, true);
            app.OBJFileButton.Text = 'OBJ File';
            app.TextureFileButton = uibutton(app.UIFigure, 'push');
            app.TextureFileButton.ButtonPushedFcn = createCallbackFcn(app, @TextureFileButtonPushed, true);
            app.TextureFileButton.Text = 'Texture File';
            app.PreviewOBJButton = uibutton(app.UIFigure, 'push');
            app.PreviewOBJButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewOBJButtonPushed, true);
            app.PreviewOBJButton.Text = 'Preview OBJ';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Output Values'; ''};
            app.UITable.RowName = {''};
            app.HottestTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.HottestTemperatureCEditFieldLabel.Text = 'Hottest Temperature (C)';
            app.HottestTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColdestTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.ColdestTemperatureCEditFieldLabel.Text = 'Coldest Temperature (C)';
            app.ColdestTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTemperatureCEditFieldLabel.Text = 'Ambient Temperature (C)';
            app.AmbientTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.WallAngledegreesEditFieldLabel = uilabel(app.UIFigure);
            app.WallAngledegreesEditFieldLabel.Text = 'Wall Angle (degrees)';
            app.WallAngledegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WallAngledegreesEditField.Enable = 'off';
            app.EmissivityEditFieldLabel = uilabel(app.UIFigure);
            app.EmissivityEditFieldLabel.Text = 'Emissivity';
            app.EmissivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.GroundTemperatureCEditField_2Label = uilabel(app.UIFigure);
            app.GroundTemperatureCEditField_2Label.Text = 'Ground Temperature (C)';
            app.GroundTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.DewPointTemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.DewPointTemperatureCEditFieldLabel.Text = 'Dew Point Temperature (C)';
            app.DewPointTemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RealDistanceEditFieldLabel.Text = 'Real Distance';
            app.RealDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.OBJDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.OBJDistanceEditFieldLabel.Text = 'OBJ Distance';
            app.OBJDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.UseGlobalAngleCheckBox = uicheckbox(app.UIFigure);
            app.UseGlobalAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGlobalAngleCheckBoxValueChanged, true);
            app.UseGlobalAngleCheckBox.Text = 'Use Global Angle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Thermal_OBJ_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end